classdef ComparisonMode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ComparisonModeUIFigure       matlab.ui.Figure
        UIAxesTime                   matlab.ui.control.UIAxes
        UIAxesFrequency              matlab.ui.control.UIAxes
        AnalyzerSettingsPanel        matlab.ui.container.Panel
        FFTSizeLabel                 matlab.ui.control.Label
        WindowLengthField            matlab.ui.control.NumericEditField
        WindowLabel                  matlab.ui.control.Label
        WindowDropDown               matlab.ui.control.DropDown
        LengthLabel                  matlab.ui.control.Label
        FFTSizeField                 matlab.ui.control.NumericEditField
        AnalyzerSettingsPanel_2      matlab.ui.container.Panel
        FFTSizeLabel_2               matlab.ui.control.Label
        FFTSizeField1                matlab.ui.control.NumericEditField
        WindowLabel_2                matlab.ui.control.Label
        WindowDropDown1              matlab.ui.control.DropDown
        LengthLabel_2                matlab.ui.control.Label
        WindowLengthField1           matlab.ui.control.NumericEditField
        UIAxesTime1                  matlab.ui.control.UIAxes
        UIAxesFrequency1             matlab.ui.control.UIAxes
        FunctionPanel                matlab.ui.container.Panel
        SamplingLabel                matlab.ui.control.Label
        SamplingFrequencyField       matlab.ui.control.NumericEditField
        FunctionLabel                matlab.ui.control.Label
        FunctionDropDown             matlab.ui.control.DropDown
        LengthLabel_3                matlab.ui.control.Label
        FunctionParameterField       matlab.ui.control.NumericEditField
        FrequencyLabel               matlab.ui.control.Label
        FunctionPanel1               matlab.ui.container.Panel
        SamplingLabel_2              matlab.ui.control.Label
        SamplingFrequencyField1      matlab.ui.control.NumericEditField
        FunctionLabel1               matlab.ui.control.Label
        FunctionDropDown1            matlab.ui.control.DropDown
        LengthLabel1                 matlab.ui.control.Label
        FunctionParameterField1      matlab.ui.control.NumericEditField
        FrequencyLabel_2             matlab.ui.control.Label
        PlotSettingsPanel            matlab.ui.container.Panel
        FrequencyDomainLabel         matlab.ui.control.Label
        XScaleLabel                  matlab.ui.control.Label
        YScaleLabel                  matlab.ui.control.Label
        FrequencyXScale              matlab.ui.control.DropDown
        FrequencyYScale              matlab.ui.control.DropDown
        TimeDomainLabel              matlab.ui.control.Label
        XScaleLabel_2                matlab.ui.control.Label
        YScaleLabel_2                matlab.ui.control.Label
        TimeXScale                   matlab.ui.control.DropDown
        TimeYScale                   matlab.ui.control.DropDown
        PlotTypeLabel                matlab.ui.control.Label
        FrequencyPlotType            matlab.ui.control.DropDown
        PlotSettingsPanel_2          matlab.ui.container.Panel
        FrequencyDomainLabel_2       matlab.ui.control.Label
        XScaleLabel_3                matlab.ui.control.Label
        YScaleLabel_3                matlab.ui.control.Label
        FrequencyXScale1             matlab.ui.control.DropDown
        FrequencyYScale1             matlab.ui.control.DropDown
        TimeDomainLabel_2            matlab.ui.control.Label
        XScaleLabel_4                matlab.ui.control.Label
        YScaleLabel_4                matlab.ui.control.Label
        TimeXScale1                  matlab.ui.control.DropDown
        TimeYScale1                  matlab.ui.control.DropDown
        PlotTypeLabel_2              matlab.ui.control.Label
        FrequencyPlotType_2          matlab.ui.control.DropDown
        SoundFilesPanel              matlab.ui.container.Panel
        PleaseselectFileModeLabel    matlab.ui.control.Label
        andpastethefilepathLabel     matlab.ui.control.Label
        LoadButton                   matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        FilePathEditField            matlab.ui.control.EditField
        FilePathLabel                matlab.ui.control.Label
        SoundFilesPanel_2            matlab.ui.container.Panel
        PleaseselectFileModeLabel_2  matlab.ui.control.Label
        andpastethefilepathLabel_2   matlab.ui.control.Label
        FilePathEditField1           matlab.ui.control.EditField
        LoadButton1                  matlab.ui.control.Button
        PlayButton1                  matlab.ui.control.Button
        StopButton1                  matlab.ui.control.Button
        FilePathLabel_2              matlab.ui.control.Label
        AnalyzerModeButtonGroup      matlab.ui.container.ButtonGroup
        FileModeButton               matlab.ui.control.ToggleButton
        FunctionModeButton           matlab.ui.control.ToggleButton
        AnalyzerModeButtonGroup1     matlab.ui.container.ButtonGroup
        FileModeButton1              matlab.ui.control.ToggleButton
        FunctionModeButton1          matlab.ui.control.ToggleButton
        PlotButton                   matlab.ui.control.Button
        PlotButton_2                 matlab.ui.control.Button
        FirstSignalLabel             matlab.ui.control.Label
        SecondSignalLabel            matlab.ui.control.Label
        BacktoMainAnalyzerButton     matlab.ui.control.Button
    end


    methods (Access = public)
    
        function func = tri(app,L)
            %%Generates a triangular pulse of length L
            L=L-1;
            t=0:1:L;
            func=(L/2-abs(t-L/2))/(L/2);
        end
        
    end


    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
             L=app.WindowLengthField.Value;
             %%Defines the window chosen by the user
            if (strcmp(app.WindowDropDown.Value,'Rectangular')==1)
                w=ones(1,L);
            elseif (strcmp(app.WindowDropDown.Value,'Triangular')==1)
                w=tri(app,L);
            elseif (strcmp(app.WindowDropDown.Value,'Hamming')==1)
                temp=0:L-1;
                w=0.54-0.46*cos(2*pi*temp/(L-1));
            elseif (strcmp(app.WindowDropDown.Value,'Hanning')==1)
                temp=0:L-1;
                w=(sin(pi*temp/(L-1))).^2;
            end
            if(app.FunctionModeButton.Value==1)
                if (strcmp(app.FunctionDropDown.Value,'Rectangle')==1)
                    %%reads the parameters of the function
                    L=app.FunctionParameterField.Value;
                    fs=app.SamplingFrequencyField.Value;
                    N=app.FFTSizeField.Value;
                    t=0:1/fs:(L-1)/fs;
                    y=ones(1,L);
                    %windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    %time domain plot
                    plot(app.UIAxesTime,t(1:length(y)),y);
                    %frequency domain plot
                    type=app.FrequencyPlotType.Value;
                    f=-fs/2:fs/N:fs/2-fs/N;
                    f_y=fft(y,N);
                    if(strcmp(type,'Magnitude')==1)
                        f_y=abs(fftshift(f_y));
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='|X|';
                    elseif(strcmp(type,'Phase')==1)
                        f_y=angle(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='\angleX';
                    elseif(strcmp(type,'Real')==1)
                        f_y=real(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Real';
                    elseif(strcmp(type,'Imaginary')==1)
                        f_y=imag(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Imaginary';
                    end
                elseif (strcmp(app.FunctionDropDown.Value,'Triangle')==1)
                    %%reads the parameters of the function
                    L=app.FunctionParameterField.Value;
                    fs=app.SamplingFrequencyField.Value;
                    N=app.FFTSizeField.Value;
                    %time domain plot
                    t=0:1/fs:(L)/fs;
                    y=tri(app,L);
                    %Windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    %time domain plot
                    plot(app.UIAxesTime,t(1:length(y)),y);
                    %frequency domain plot
                    type=app.FrequencyPlotType.Value;
                    f=-fs/2:fs/N:fs/2-fs/N;
                    f_y=fft(y,N);
                    if(strcmp(type,'Magnitude')==1)
                        f_y=abs(fftshift(f_y));
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='|X|';
                    elseif(strcmp(type,'Phase')==1)
                        f_y=angle(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='\angleX';
                    elseif(strcmp(type,'Real')==1)
                        f_y=real(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Real';
                    elseif(strcmp(type,'Imaginary')==1)
                        f_y=imag(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Imaginary';
                    end
                elseif (strcmp(app.FunctionDropDown.Value,'Sine')==1)
                    %%reads the parameters of the function
                    T=app.FunctionParameterField.Value;
                    fs=app.SamplingFrequencyField.Value;
                    N=app.FFTSizeField.Value;
                    omega=2*pi/T;
                    t=0:1/fs:2*T;
                    y=sin(omega*t);
                    %windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    %time domain plot
                    plot(app.UIAxesTime,t(1:length(y)),y);
                    %frequency domain plot
                    type=app.FrequencyPlotType.Value;
                    f=-fs/2:fs/N:fs/2-fs/N;
                    f_y=fft(y,N);
                    if(strcmp(type,'Magnitude')==1)
                        f_y=abs(fftshift(f_y));
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='|X|';
                    elseif(strcmp(type,'Phase')==1)
                        f_y=angle(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='\angleX';
                    elseif(strcmp(type,'Real')==1)
                        f_y=real(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Real';
                    elseif(strcmp(type,'Imaginary')==1)
                        f_y=imag(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Imaginary';
                    end
                end
            elseif(app.FileModeButton.Value==1)
                if(exist(app.FilePathEditField.Value)==2)
                    %%Reads the Audio file
                    [y,fs]=audioread(app.FilePathEditField.Value);
                    y=transpose(y);
                    N=app.FFTSizeField.Value;
                    L=length(y);
                    t=0:1/fs:(L-1)/fs;
                    %windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    %time domain plot
                    plot(app.UIAxesTime,t(1:length(y)),y);
                    %frequency domain plot
                    type=app.FrequencyPlotType.Value;
                    f=-fs/2:fs/N:fs/2-fs/N;
                    f_y=fft(y,N);
                    if(strcmp(type,'Magnitude')==1)
                        f_y=abs(fftshift(f_y));
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='|X|';
                    elseif(strcmp(type,'Phase')==1)
                        f_y=angle(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='\angleX';
                    elseif(strcmp(type,'Real')==1)
                        f_y=real(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Real';
                    elseif(strcmp(type,'Imaginary')==1)
                        f_y=imag(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Imaginary';
                    end
                else
                    uialert(app.SpectralAnalyzerUIFigure,'Something seems to be wrong with your file','Invalid File');
                end
            end
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
              L=app.WindowLengthField1.Value;
              %%Defines the window chosen by the user
            if (strcmp(app.WindowDropDown1.Value,'Rectangular')==1)
                w=ones(1,L);
            elseif (strcmp(app.WindowDropDown1.Value,'Triangular')==1)
                w=tri(app,L);
            elseif (strcmp(app.WindowDropDown1.Value,'Hamming')==1)
                temp=0:L-1;
                w=0.54-0.46*cos(2*pi*temp/(L-1));
            elseif (strcmp(app.WindowDropDown1.Value,'Hanning')==1)
                temp=0:L-1;
                w=(sin(pi*temp/(L-1))).^2;
            end
            if(app.FunctionModeButton1.Value==1)
                if (strcmp(app.FunctionDropDown1.Value,'Rectangle')==1)
                    %%reads the parameters of the function
                    L=app.FunctionParameterField1.Value;
                    fs=app.SamplingFrequencyField1.Value;
                    N=app.FFTSizeField1.Value;
                    t=0:1/fs:(L-1)/fs;
                    y=ones(1,L);
                    %windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    %time domain plot
                    plot(app.UIAxesTime1,t(1:length(y)),y);
                    %frequency domain plot
                    type=app.FrequencyPlotType_2.Value;
                    f=-fs/2:fs/N:fs/2-fs/N;
                    f_y=fft(y,N);
                    if(strcmp(type,'Magnitude')==1)
                        f_y=abs(fftshift(f_y));
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='|X|';
                    elseif(strcmp(type,'Phase')==1)
                        f_y=angle(f_y);
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='\angleX';
                    elseif(strcmp(type,'Real')==1)
                        f_y=real(f_y);
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='Real';
                    elseif(strcmp(type,'Imaginary')==1)
                        f_y=imag(f_y);
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='Imaginary';
                    end
                elseif (strcmp(app.FunctionDropDown1.Value,'Triangle')==1)
                    %%reads the parameters of the function
                    L=app.FunctionParameterField1.Value;
                    fs=app.SamplingFrequencyField1.Value;
                    N=app.FFTSizeField1.Value;
                    %time domain plot
                    t=0:1/fs:(L)/fs;
                    y=tri(app,L);
                    %Windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    %time domain plot
                    plot(app.UIAxesTime1,t(1:length(y)),y);
                    %frequency domain plot
                    type=app.FrequencyPlotType_2.Value;
                    f=-fs/2:fs/N:fs/2-fs/N;
                    f_y=fft(y,N);
                    if(strcmp(type,'Magnitude')==1)
                        f_y=abs(fftshift(f_y));
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='|X|';
                    elseif(strcmp(type,'Phase')==1)
                        f_y=angle(f_y);
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='\angleX';
                    elseif(strcmp(type,'Real')==1)
                        f_y=real(f_y);
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='Real';
                    elseif(strcmp(type,'Imaginary')==1)
                        f_y=imag(f_y);
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='Imaginary';
                    end
                elseif (strcmp(app.FunctionDropDown1.Value,'Sine')==1)
                    %%reads the parameters of the function
                    T=app.FunctionParameterField1.Value;
                    fs=app.SamplingFrequencyField1.Value;
                    N=app.FFTSizeField1.Value;
                    omega=2*pi/T;
                    t=0:1/fs:2*T;
                    y=sin(omega*t);
                    %windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    %time domain plot
                    plot(app.UIAxesTime1,t(1:length(y)),y);
                    %frequency domain plot
                    type=app.FrequencyPlotType_2.Value;
                    f=-fs/2:fs/N:fs/2-fs/N;
                    f_y=fft(y,N);
                    if(strcmp(type,'Magnitude')==1)
                        f_y=abs(fftshift(f_y));
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='|X|';
                    elseif(strcmp(type,'Phase')==1)
                        f_y=angle(f_y);
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='\angleX';
                    elseif(strcmp(type,'Real')==1)
                        f_y=real(f_y);
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='Real';
                    elseif(strcmp(type,'Imaginary')==1)
                        f_y=imag(f_y);
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='Imaginary';
                    end
                end
            elseif(app.FileModeButton1.Value==1)
                if(exist(app.FilePathEditField1.Value)==2)
                    %%reads the audio file
                    [y,fs]=audioread(app.FilePathEditField1.Value);
                    N=app.FFTSizeField1.Value;
                    y=transpose(y);
                    L=length(y);
                    t=0:1/fs:(L-1)/fs;
                    %windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    %time domain plot
                    plot(app.UIAxesTime1,t(1:length(y)),y);
                    %frequency domain plot
                   type=app.FrequencyPlotType_2.Value;
                    f=-fs/2:fs/N:fs/2-fs/N;
                    f_y=fft(y,N);
                    if(strcmp(type,'Magnitude')==1)
                        f_y=abs(fftshift(f_y));
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='|X|';
                    elseif(strcmp(type,'Phase')==1)
                        f_y=angle(f_y);
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='\angleX';
                    elseif(strcmp(type,'Real')==1)
                        f_y=real(f_y);
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='Real';
                    elseif(strcmp(type,'Imaginary')==1)
                        f_y=imag(f_y);
                        plot(app.UIAxesFrequency1,f,f_y);
                        app.UIAxesFrequency1.YLabel.String='Imaginary';
                    end
                else
                    uialert(app.SpectralAnalyzerUIFigure,'Something seems to be wrong with your file','Invalid File');
                end
            end
        end

        % Value changed function: FrequencyXScale
        function FrequencyXScaleValueChanged(app, event)
            app.UIAxesFrequency.XScale = app.FrequencyXScale.Value;
        end

        % Value changed function: FrequencyYScale
        function FrequencyYScaleValueChanged(app, event)
            app.UIAxesFrequency.YScale = app.FrequencyYScale.Value;
        end

        % Value changed function: TimeXScale
        function TimeXScaleValueChanged(app, event)
             app.UIAxesTime.XScale = app.TimeXScale.Value;
        end

        % Value changed function: TimeYScale
        function TimeYScaleValueChanged(app, event)
            app.UIAxesTime.YScale = app.TimeYScale.Value;
        end

        % Value changed function: FrequencyXScale1
        function FrequencyXScale1ValueChanged(app, event)
            app.UIAxesFrequency1.XScale = app.FrequencyXScale1.Value;
        end

        % Value changed function: FrequencyYScale1
        function FrequencyYScale1ValueChanged(app, event)
            app.UIAxesFrequency1.YScale = app.FrequencyYScale1.Value;
        end

        % Value changed function: TimeXScale1
        function TimeXScale1ValueChanged(app, event)
            app.UIAxesTime1.XScale = app.TimeXScale1.Value;    
        end

        % Value changed function: TimeYScale1
        function TimeYScale1ValueChanged(app, event)
            app.UIAxesTime1.YScale = app.TimeYScale1.Value;
        end

        % Selection changed function: AnalyzerModeButtonGroup
        function AnalyzerModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AnalyzerModeButtonGroup.SelectedObject;
            %%Changes the state of panels and objects in the panel according the analyzer mode
            if(strcmp(selectedButton.Text,'File Mode')==1)
                app.SamplingFrequencyField.Enable='off';
                app.FunctionParameterField.Enable='off';
                app.FunctionDropDown.Visible='off';
                app.FunctionLabel.Visible='off';
                app.FunctionPanel.Title='File:';
                app.LengthLabel_3.Text='Length';
                app.FilePathEditField.Enable='on';
                app.LoadButton.Enable='on';
                app.PlayButton.Enable='on';
                app.StopButton.Enable='on';
            elseif(strcmp(selectedButton.Text,'Function Mode')==1)
                app.SamplingFrequencyField.Enable='on';
                app.FunctionParameterField.Enable='on';
                app.FunctionDropDown.Visible='on';
                app.FunctionLabel.Visible='on';
                app.FunctionPanel.Title='Function:';
                app.FilePathEditField.Enable='off';
                app.LoadButton.Enable='off';
                app.PlayButton.Enable='off';
                app.StopButton.Enable='off';
            end
        end

        % Selection changed function: AnalyzerModeButtonGroup1
        function AnalyzerModeButtonGroup1SelectionChanged(app, event)
            selectedButton = app.AnalyzerModeButtonGroup1.SelectedObject;
            %%Changes the state of panels and objects in the panel according the analyzer mode
            if(strcmp(selectedButton.Text,'File Mode')==1)
                app.SamplingFrequencyField1.Enable='off';
                app.FunctionParameterField1.Enable='off';
                app.FunctionDropDown1.Visible='off';
                app.FunctionLabel1.Visible='off';
                app.FunctionPanel1.Title='File:';
                app.LengthLabel1.Text='Length';
                app.FilePathEditField1.Enable='on';
                app.LoadButton1.Enable='on';
                app.PlayButton1.Enable='on';
                app.StopButton1.Enable='on';
            elseif(strcmp(selectedButton.Text,'Function Mode')==1)
                app.SamplingFrequencyField1.Enable='on';
                app.FunctionParameterField1.Enable='on';
                app.FunctionDropDown1.Visible='on';
                app.FunctionLabel1.Visible='on';
                app.FunctionPanel1.Title='Function:';
                app.FilePathEditField1.Enable='off';
                app.LoadButton1.Enable='off';
                app.PlayButton1.Enable='off';
                app.StopButton1.Enable='off';
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if(exist(app.FilePathEditField.Value)==2)
                %%Loads the audio file and alerts the user if the file is not found
                [y,Fs]=audioread(app.FilePathEditField.Value);
                app.SamplingFrequencyField.Value=Fs;
                app.FunctionParameterField.Value=length(y);
                uialert(app.ComparisonModeUIFigure,'Your file is loaded','')
            else
                uialert(app.ComparisonModeUIFigure,'file not found','Invalid File');
            end
        end

        % Button pushed function: LoadButton1
        function LoadButton1Pushed(app, event)
            if(exist(app.FilePathEditField1.Value)==2)
                %%Loads the audio file and alerts the user if the file is not found
                [y,Fs]=audioread(app.FilePathEditField1.Value);
                app.SamplingFrequencyField1.Value=Fs;
                app.FunctionParameterField1.Value=length(y);
                uialert(app.ComparisonModeUIFigure,'Your file is loaded','')
            else
                uialert(app.ComparisonModeUIFigure,'file not found','Invalid File');
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if(exist(app.FilePathEditField.Value)==2)
                %%plays the audio file and alerts the user if the file is not found
                [y,Fs]=audioread(app.FilePathEditField.Value);
                sound(y,Fs);
            else
                uialert(app.ComparisonModeUIFigure,'file not found','Invalid File');
            end
        end

        % Button pushed function: PlayButton1
        function PlayButton1Pushed(app, event)
            if(exist(app.FilePathEditField1.Value)==2)
                %%plays the audio file and alerts the user if the file is not found
                [y,Fs]=audioread(app.FilePathEditField1.Value);
                sound(y,Fs);
            else
                uialert(app.ComparisonModeUIFigure,'file not found','Invalid File');
            end
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            value = app.FunctionDropDown.Value;
            %%sets the label to its appropriate name according to the chosen function
            if(strcmp(value,'Sine')==1)
                app.LengthLabel_3.Text='Period:';
            elseif(strcmp(value,'Rectangle')==1)
                 app.LengthLabel_3.Text='Length:';
            elseif(strcmp(value,'Triangle')==1)
                app.LengthLabel_3.Text='Length:';
            end
        end

        % Value changed function: FunctionDropDown1
        function FunctionDropDown1ValueChanged(app, event)
            value = app.FunctionDropDown1.Value;
            %%sets the label to its appropriate name according to the chosen function
            if(strcmp(value,'Sine')==1)
                app.LengthLabel1.Text='Period:';
            elseif(strcmp(value,'Rectangle')==1)
                 app.LengthLabel1.Text='Length:';
            elseif(strcmp(value,'Triangle')==1)
                app.LengthLabel1.Text='Length:';
            end
        end

        % Button pushed function: BacktoMainAnalyzerButton
        function BacktoMainAnalyzerButtonPushed(app, event)
            SpectralAnalyzer();
            delete(app.ComparisonModeUIFigure);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Button pushed function: StopButton1
        function StopButton1Pushed(app, event)
            clear sound;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ComparisonModeUIFigure = uifigure;
            app.ComparisonModeUIFigure.Name = 'Comparison Mode';
            setAutoResize(app, app.ComparisonModeUIFigure, true)
            app.UIAxesTime = uiaxes(app.ComparisonModeUIFigure);
            title(app.UIAxesTime, 'Time Domain');
            xlabel(app.UIAxesTime, 't');
            ylabel(app.UIAxesTime, 'X');
            app.UIAxesFrequency = uiaxes(app.ComparisonModeUIFigure);
            title(app.UIAxesFrequency, 'Frequency Domain');
            xlabel(app.UIAxesFrequency, 'f');
            ylabel(app.UIAxesFrequency, 'X');
            app.AnalyzerSettingsPanel = uipanel(app.ComparisonModeUIFigure);
            app.AnalyzerSettingsPanel.Title = 'Analyzer Settings';
            app.FFTSizeLabel = uilabel(app.AnalyzerSettingsPanel);
            app.FFTSizeLabel.Text = 'FFT Size:';
            app.WindowLengthField = uieditfield(app.AnalyzerSettingsPanel, 'numeric');
            app.WindowLengthField.Limits = [1 Inf];
            app.WindowLengthField.Value = 1;
            app.WindowLabel = uilabel(app.AnalyzerSettingsPanel);
            app.WindowLabel.Text = 'Window:';
            app.WindowDropDown = uidropdown(app.AnalyzerSettingsPanel);
            app.WindowDropDown.Items = {'Rectangular', 'Triangular', 'Hamming', 'Hanning'};
            app.WindowDropDown.Value = 'Rectangular';
            app.LengthLabel = uilabel(app.AnalyzerSettingsPanel);
            app.LengthLabel.Text = 'Length:';
            app.FFTSizeField = uieditfield(app.AnalyzerSettingsPanel, 'numeric');
            app.FFTSizeField.Limits = [1 Inf];
            app.FFTSizeField.Value = 1;
            app.AnalyzerSettingsPanel_2 = uipanel(app.ComparisonModeUIFigure);
            app.AnalyzerSettingsPanel_2.Title = 'Analyzer Settings';
            app.FFTSizeLabel_2 = uilabel(app.AnalyzerSettingsPanel_2);
            app.FFTSizeLabel_2.Text = 'FFT Size:';
            app.FFTSizeField1 = uieditfield(app.AnalyzerSettingsPanel_2, 'numeric');
            app.FFTSizeField1.Limits = [1 Inf];
            app.FFTSizeField1.Value = 1;
            app.WindowLabel_2 = uilabel(app.AnalyzerSettingsPanel_2);
            app.WindowLabel_2.Text = 'Window:';
            app.WindowDropDown1 = uidropdown(app.AnalyzerSettingsPanel_2);
            app.WindowDropDown1.Items = {'Rectangular', 'Triangular', 'Hamming', 'Hanning'};
            app.WindowDropDown1.Value = 'Rectangular';
            app.LengthLabel_2 = uilabel(app.AnalyzerSettingsPanel_2);
            app.LengthLabel_2.Text = 'Length:';
            app.WindowLengthField1 = uieditfield(app.AnalyzerSettingsPanel_2, 'numeric');
            app.WindowLengthField1.Limits = [1 Inf];
            app.WindowLengthField1.Value = 1;
            app.UIAxesTime1 = uiaxes(app.ComparisonModeUIFigure);
            title(app.UIAxesTime1, 'Time Domain');
            xlabel(app.UIAxesTime1, 't');
            ylabel(app.UIAxesTime1, 'X');
            app.UIAxesFrequency1 = uiaxes(app.ComparisonModeUIFigure);
            title(app.UIAxesFrequency1, 'Frequency Domain');
            xlabel(app.UIAxesFrequency1, 'f');
            ylabel(app.UIAxesFrequency1, 'X');
            app.FunctionPanel = uipanel(app.ComparisonModeUIFigure);
            app.FunctionPanel.Title = 'Function:';
            app.SamplingLabel = uilabel(app.FunctionPanel);
            app.SamplingLabel.Text = 'Sampling';
            app.SamplingFrequencyField = uieditfield(app.FunctionPanel, 'numeric');
            app.SamplingFrequencyField.Limits = [1 Inf];
            app.SamplingFrequencyField.Value = 1;
            app.FunctionLabel = uilabel(app.FunctionPanel);
            app.FunctionLabel.Text = 'Function:';
            app.FunctionDropDown = uidropdown(app.FunctionPanel);
            app.FunctionDropDown.Items = {'Rectangle', 'Triangle', 'Sine', ''};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Value = 'Rectangle';
            app.LengthLabel_3 = uilabel(app.FunctionPanel);
            app.LengthLabel_3.Text = 'Length:';
            app.FunctionParameterField = uieditfield(app.FunctionPanel, 'numeric');
            app.FunctionParameterField.Limits = [0 Inf];
            app.FunctionParameterField.Value = 1;
            app.FrequencyLabel = uilabel(app.FunctionPanel);
            app.FrequencyLabel.Text = 'Frequency:';
            app.FunctionPanel1 = uipanel(app.ComparisonModeUIFigure);
            app.FunctionPanel1.Title = 'Function:';
            app.SamplingLabel_2 = uilabel(app.FunctionPanel1);
            app.SamplingLabel_2.Text = 'Sampling';
            app.SamplingFrequencyField1 = uieditfield(app.FunctionPanel1, 'numeric');
            app.SamplingFrequencyField1.Limits = [1 Inf];
            app.SamplingFrequencyField1.Value = 1;
            app.FunctionLabel1 = uilabel(app.FunctionPanel1);
            app.FunctionLabel1.Text = 'Function:';
            app.FunctionDropDown1 = uidropdown(app.FunctionPanel1);
            app.FunctionDropDown1.Items = {'Rectangle', 'Triangle', 'Sine', ''};
            app.FunctionDropDown1.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDown1ValueChanged, true);
            app.FunctionDropDown1.Value = 'Rectangle';
            app.LengthLabel1 = uilabel(app.FunctionPanel1);
            app.LengthLabel1.Text = 'Length:';
            app.FunctionParameterField1 = uieditfield(app.FunctionPanel1, 'numeric');
            app.FunctionParameterField1.Limits = [0 Inf];
            app.FunctionParameterField1.Value = 1;
            app.FrequencyLabel_2 = uilabel(app.FunctionPanel1);
            app.FrequencyLabel_2.Text = 'Frequency:';
            app.PlotSettingsPanel = uipanel(app.ComparisonModeUIFigure);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.FrequencyDomainLabel = uilabel(app.PlotSettingsPanel);
            app.FrequencyDomainLabel.Text = 'Frequency Domain:';
            app.XScaleLabel = uilabel(app.PlotSettingsPanel);
            app.XScaleLabel.Text = 'XScale:';
            app.YScaleLabel = uilabel(app.PlotSettingsPanel);
            app.YScaleLabel.Text = 'YScale:';
            app.FrequencyXScale = uidropdown(app.PlotSettingsPanel);
            app.FrequencyXScale.Items = {'Linear', 'Log', ''};
            app.FrequencyXScale.ValueChangedFcn = createCallbackFcn(app, @FrequencyXScaleValueChanged, true);
            app.FrequencyXScale.Value = 'Linear';
            app.FrequencyYScale = uidropdown(app.PlotSettingsPanel);
            app.FrequencyYScale.Items = {'Linear', 'Log', ''};
            app.FrequencyYScale.ValueChangedFcn = createCallbackFcn(app, @FrequencyYScaleValueChanged, true);
            app.FrequencyYScale.Value = 'Linear';
            app.TimeDomainLabel = uilabel(app.PlotSettingsPanel);
            app.TimeDomainLabel.Text = 'Time Domain:';
            app.XScaleLabel_2 = uilabel(app.PlotSettingsPanel);
            app.XScaleLabel_2.Text = 'XScale:';
            app.YScaleLabel_2 = uilabel(app.PlotSettingsPanel);
            app.YScaleLabel_2.Text = 'YScale:';
            app.TimeXScale = uidropdown(app.PlotSettingsPanel);
            app.TimeXScale.Items = {'Linear', 'Log'};
            app.TimeXScale.ValueChangedFcn = createCallbackFcn(app, @TimeXScaleValueChanged, true);
            app.TimeXScale.Value = 'Linear';
            app.TimeYScale = uidropdown(app.PlotSettingsPanel);
            app.TimeYScale.Items = {'Linear', 'Log'};
            app.TimeYScale.ValueChangedFcn = createCallbackFcn(app, @TimeYScaleValueChanged, true);
            app.TimeYScale.Value = 'Linear';
            app.PlotTypeLabel = uilabel(app.PlotSettingsPanel);
            app.PlotTypeLabel.Text = 'Plot Type:';
            app.FrequencyPlotType = uidropdown(app.PlotSettingsPanel);
            app.FrequencyPlotType.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary'};
            app.FrequencyPlotType.Value = 'Magnitude';
            app.PlotSettingsPanel_2 = uipanel(app.ComparisonModeUIFigure);
            app.PlotSettingsPanel_2.Title = 'Plot Settings';
            app.FrequencyDomainLabel_2 = uilabel(app.PlotSettingsPanel_2);
            app.FrequencyDomainLabel_2.Text = 'Frequency Domain:';
            app.XScaleLabel_3 = uilabel(app.PlotSettingsPanel_2);
            app.XScaleLabel_3.Text = 'XScale:';
            app.YScaleLabel_3 = uilabel(app.PlotSettingsPanel_2);
            app.YScaleLabel_3.Text = 'YScale:';
            app.FrequencyXScale1 = uidropdown(app.PlotSettingsPanel_2);
            app.FrequencyXScale1.Items = {'Linear', 'Log', ''};
            app.FrequencyXScale1.ValueChangedFcn = createCallbackFcn(app, @FrequencyXScale1ValueChanged, true);
            app.FrequencyXScale1.Value = 'Linear';
            app.FrequencyYScale1 = uidropdown(app.PlotSettingsPanel_2);
            app.FrequencyYScale1.Items = {'Linear', 'Log', ''};
            app.FrequencyYScale1.ValueChangedFcn = createCallbackFcn(app, @FrequencyYScale1ValueChanged, true);
            app.FrequencyYScale1.Value = 'Linear';
            app.TimeDomainLabel_2 = uilabel(app.PlotSettingsPanel_2);
            app.TimeDomainLabel_2.Text = 'Time Domain:';
            app.XScaleLabel_4 = uilabel(app.PlotSettingsPanel_2);
            app.XScaleLabel_4.Text = 'XScale:';
            app.YScaleLabel_4 = uilabel(app.PlotSettingsPanel_2);
            app.YScaleLabel_4.Text = 'YScale:';
            app.TimeXScale1 = uidropdown(app.PlotSettingsPanel_2);
            app.TimeXScale1.Items = {'Linear', 'Log'};
            app.TimeXScale1.ValueChangedFcn = createCallbackFcn(app, @TimeXScale1ValueChanged, true);
            app.TimeXScale1.Value = 'Linear';
            app.TimeYScale1 = uidropdown(app.PlotSettingsPanel_2);
            app.TimeYScale1.Items = {'Linear', 'Log'};
            app.TimeYScale1.ValueChangedFcn = createCallbackFcn(app, @TimeYScale1ValueChanged, true);
            app.TimeYScale1.Value = 'Linear';
            app.PlotTypeLabel_2 = uilabel(app.PlotSettingsPanel_2);
            app.PlotTypeLabel_2.Text = 'Plot Type:';
            app.FrequencyPlotType_2 = uidropdown(app.PlotSettingsPanel_2);
            app.FrequencyPlotType_2.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary'};
            app.FrequencyPlotType_2.Value = 'Magnitude';
            app.SoundFilesPanel = uipanel(app.ComparisonModeUIFigure);
            app.SoundFilesPanel.Title = 'Sound Files';
            app.PleaseselectFileModeLabel = uilabel(app.SoundFilesPanel);
            app.PleaseselectFileModeLabel.Text = 'Please select File Mode';
            app.andpastethefilepathLabel = uilabel(app.SoundFilesPanel);
            app.andpastethefilepathLabel.Text = 'and paste the file path:';
            app.LoadButton = uibutton(app.SoundFilesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.PlayButton = uibutton(app.SoundFilesPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.SoundFilesPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.FilePathEditField = uieditfield(app.SoundFilesPanel, 'text');
            app.FilePathEditField.Enable = 'off';
            app.FilePathLabel = uilabel(app.SoundFilesPanel);
            app.FilePathLabel.Text = 'File Path:';
            app.SoundFilesPanel_2 = uipanel(app.ComparisonModeUIFigure);
            app.SoundFilesPanel_2.Title = 'Sound Files';
            app.PleaseselectFileModeLabel_2 = uilabel(app.SoundFilesPanel_2);
            app.PleaseselectFileModeLabel_2.Text = 'Please select File Mode';
            app.andpastethefilepathLabel_2 = uilabel(app.SoundFilesPanel_2);
            app.andpastethefilepathLabel_2.Text = 'and paste the file path:';
            app.FilePathEditField1 = uieditfield(app.SoundFilesPanel_2, 'text');
            app.FilePathEditField1.Enable = 'off';
            app.LoadButton1 = uibutton(app.SoundFilesPanel_2, 'push');
            app.LoadButton1.ButtonPushedFcn = createCallbackFcn(app, @LoadButton1Pushed, true);
            app.LoadButton1.Enable = 'off';
            app.LoadButton1.Text = 'Load';
            app.PlayButton1 = uibutton(app.SoundFilesPanel_2, 'push');
            app.PlayButton1.ButtonPushedFcn = createCallbackFcn(app, @PlayButton1Pushed, true);
            app.PlayButton1.Enable = 'off';
            app.PlayButton1.Text = 'Play';
            app.StopButton1 = uibutton(app.SoundFilesPanel_2, 'push');
            app.StopButton1.ButtonPushedFcn = createCallbackFcn(app, @StopButton1Pushed, true);
            app.StopButton1.Enable = 'off';
            app.StopButton1.Text = 'Stop';
            app.FilePathLabel_2 = uilabel(app.SoundFilesPanel_2);
            app.FilePathLabel_2.Text = 'File Path:';
            app.AnalyzerModeButtonGroup = uibuttongroup(app.ComparisonModeUIFigure);
            app.AnalyzerModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalyzerModeButtonGroupSelectionChanged, true);
            app.AnalyzerModeButtonGroup.Title = 'Analyzer Mode:';
            app.FileModeButton = uitogglebutton(app.AnalyzerModeButtonGroup);
            app.FileModeButton.Text = 'File Mode';
            app.FunctionModeButton = uitogglebutton(app.AnalyzerModeButtonGroup);
            app.FunctionModeButton.Text = 'Function Mode';
            app.FunctionModeButton.Value = true;
            app.AnalyzerModeButtonGroup1 = uibuttongroup(app.ComparisonModeUIFigure);
            app.AnalyzerModeButtonGroup1.SelectionChangedFcn = createCallbackFcn(app, @AnalyzerModeButtonGroup1SelectionChanged, true);
            app.AnalyzerModeButtonGroup1.Title = 'Analyzer Mode:';
            app.FileModeButton1 = uitogglebutton(app.AnalyzerModeButtonGroup1);
            app.FileModeButton1.Text = 'File Mode';
            app.FunctionModeButton1 = uitogglebutton(app.AnalyzerModeButtonGroup1);
            app.FunctionModeButton1.Text = 'Function Mode';
            app.FunctionModeButton1.Value = true;
            app.PlotButton = uibutton(app.ComparisonModeUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotButton_2 = uibutton(app.ComparisonModeUIFigure, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.FirstSignalLabel = uilabel(app.ComparisonModeUIFigure);
            app.FirstSignalLabel.Text = 'First Signal';
            app.SecondSignalLabel = uilabel(app.ComparisonModeUIFigure);
            app.SecondSignalLabel.Text = 'Second Signal';
            app.BacktoMainAnalyzerButton = uibutton(app.ComparisonModeUIFigure, 'push');
            app.BacktoMainAnalyzerButton.ButtonPushedFcn = createCallbackFcn(app, @BacktoMainAnalyzerButtonPushed, true);
            app.BacktoMainAnalyzerButton.Text = 'Back to Main Analyzer';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ComparisonMode()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ComparisonModeUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ComparisonModeUIFigure)
        end
    end
end