classdef DiscreteConvolution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiscreteConvolutionUIFigure  matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes4                      matlab.ui.control.UIAxes
        UIAxes5                      matlab.ui.control.UIAxes
        UIAxes6                      matlab.ui.control.UIAxes
        FirstSignalPanel             matlab.ui.container.Panel
        FunctionLabel                matlab.ui.control.Label
        FunctionDropDown             matlab.ui.control.DropDown
        LengthLabel                  matlab.ui.control.Label
        FunctionParameterField       matlab.ui.control.NumericEditField
        AmplitudeLabel               matlab.ui.control.Label
        FunctionAmplitudeField       matlab.ui.control.NumericEditField
        SecondSignalPanel            matlab.ui.container.Panel
        FunctionLabel_2              matlab.ui.control.Label
        FunctionDropDown_2           matlab.ui.control.DropDown
        LengthLabel_2                matlab.ui.control.Label
        FunctionParameterField_2     matlab.ui.control.NumericEditField
        AmplitudeLabel_2             matlab.ui.control.Label
        FunctionAmplitudeField_2     matlab.ui.control.NumericEditField
        PlotSettingsPanel            matlab.ui.container.Panel
        XScaleLabel_2                matlab.ui.control.Label
        YScaleLabel_2                matlab.ui.control.Label
        TimeXScale                   matlab.ui.control.DropDown
        TimeYScale                   matlab.ui.control.DropDown
        PlotButton                   matlab.ui.control.Button
        VisualizationofDiscreteTimeConvolutionLabel  matlab.ui.control.Label
        SamplingLabel                matlab.ui.control.Label
        SamplingFrequencyField       matlab.ui.control.NumericEditField
        FrequencyLabel               matlab.ui.control.Label
    end


    methods (Access = public)
   
        function [y1,y2,y3,y] = Convolute(app,x,h)
            y=zeros(1,length(x)+length(h)-1);
            y1=zeros(1,length(x)+length(h)-1);
            y2=zeros(1,length(x)+length(h)-1);
            y3=zeros(1,length(x)+length(h)-1);
            for i=1:length(x)+length(h)
                for j=1:length(x)
                    %%ensures that the index is still inside the range
                     %%where the two sequences are defined
                    if(i-j+1<=length(h)&&i-j+1>=1) 
                        y(i)=y(i)+x(j)*h(i-j+1);
                    end
                end
                if (i==floor(1/4*(length(x)+length(h))))
                    y1=y;%%y1 is the convoluted sequency after 1/4 of the convolution process
                elseif (i==floor(1/2*(length(x)+length(h))))
                    y2=y;%%y2 is the convoluted sequency after 1/2 of the convolution process
                elseif (i==floor(3/4*(length(x)+length(h))))
                    y3=y;%%y3 is the convoluted sequency after 3/4 of the convolution process
                end
            end
        end
        
        function func = tri(app,L)
            %%Generates a triangular pulse of length L
            L=L-1;
            t=0:1:L;
            func=(L/2-abs(t-L/2))/(L/2);
        end
        
    end


    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            fs=app.SamplingFrequencyField.Value;
            if (strcmp(app.FunctionDropDown.Value,'Rectangle')==1) 
                    %%reads the parameters of the function
                    L=app.FunctionParameterField.Value;
                    A=app.FunctionAmplitudeField.Value;
                    t=0:1/fs:(L-1)/fs;
                    y=A*ones(1,L);
                    %time domain plot
                    plot(app.UIAxes,t,y);
                elseif (strcmp(app.FunctionDropDown.Value,'Triangle')==1)
                    %%reads the parameters of the function
                    L=app.FunctionParameterField.Value;
                    A=app.FunctionAmplitudeField.Value;
                    t=0:1/fs:(L)/fs;
                    y=A*tri(app,L);
                    %time domain plot
                    plot(app.UIAxes,t,y);
                elseif (strcmp(app.FunctionDropDown.Value,'Sine')==1)
                    %%reads the parameters of the function
                    T=app.FunctionParameterField.Value;
                    A=app.FunctionAmplitudeField.Value;
                    omega=2*pi/T;
                    t=0:1/fs:2*T;
                    y=A*sin(omega*t);
                    %time domain plot
                    plot(app.UIAxes,t,y);
            end
            if (strcmp(app.FunctionDropDown_2.Value,'Rectangle')==1)
                    %%reads the parameters of the function
                    L=app.FunctionParameterField_2.Value;
                    A=app.FunctionAmplitudeField_2.Value;
                    t1=0:1/fs:(L-1)/fs;
                    y1=A*ones(1,L);
                    %time domain plot
                    plot(app.UIAxes2,t1,y1);
                elseif (strcmp(app.FunctionDropDown_2.Value,'Triangle')==1)
                    %%reads the parameters of the function
                    L=app.FunctionParameterField_2.Value;
                    A=app.FunctionAmplitudeField_2.Value;
                    t1=0:1/fs:(L)/fs;
                    y1=A*tri(app,L);
                    %time domain plot
                    plot(app.UIAxes2,t1,y1);
                elseif (strcmp(app.FunctionDropDown_2.Value,'Sine')==1)
                    %%reads the parameters of the function
                    T=app.FunctionParameterField_2.Value;
                    A=app.FunctionAmplitudeField_2.Value;
                    omega=2*pi/T;
                    t1=0:1/fs:2*T;
                    y1=A*sin(omega*t);
                    %time domain plot
                    plot(app.UIAxes2,t1,y1);
            end
            t=(0:length(y)+length(y1)-2)*1/fs;
            [x1,x2,x3,x]=Convolute(app,y,y1);
            plot(app.UIAxes3,t,x1);
            plot(app.UIAxes4,t,x2);
            plot(app.UIAxes5,t,x3);
            plot(app.UIAxes6,t,x);
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            value = app.FunctionDropDown.Value;
            %%sets the label to its appropriate name according to the chosen function
            if(strcmp(value,'Sine')==1)
                app.LengthLabel.Text='Period:';
            elseif(strcmp(value,'Rectangle')==1)
                 app.LengthLabel.Text='Length:';
            elseif(strcmp(value,'Triangle')==1)
                app.LengthLabel.Text='Length:';
            end
        end

        % Value changed function: FunctionDropDown_2
        function FunctionDropDown_2ValueChanged(app, event)
            value = app.FunctionDropDown_2.Value;
            %%sets the label to its appropriate name according to the chosen function
            if(strcmp(value,'Sine')==1)
                app.LengthLabel_2.Text='Period:';
            elseif(strcmp(value,'Rectangle')==1)
                 app.LengthLabel_2.Text='Length:';
            elseif(strcmp(value,'Triangle')==1)
                app.LengthLabel_2.Text='Length:';
            end
        end

        % Value changed function: TimeXScale
        function TimeXScaleValueChanged(app, event)
            value = app.TimeXScale.Value;
            app.UIAxes.XScale=value;
            app.UIAxes2.XScale=value;
            app.UIAxes3.XScale=value;
            app.UIAxes4.XScale=value;
            app.UIAxes5.XScale=value;
            app.UIAxes6.XScale=value;
        end

        % Value changed function: TimeYScale
        function TimeYScaleValueChanged(app, event)
            value = app.TimeYScale.Value;
            app.UIAxes.YScale=value;
            app.UIAxes2.YScale=value;
            app.UIAxes3.YScale=value;
            app.UIAxes4.YScale=value;
            app.UIAxes5.YScale=value;
            app.UIAxes6.YScale=value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiscreteConvolutionUIFigure = uifigure;
            app.DiscreteConvolutionUIFigure.Name = 'Discrete Convolution';
            setAutoResize(app, app.DiscreteConvolutionUIFigure, true)
            app.UIAxes = uiaxes(app.DiscreteConvolutionUIFigure);
            title(app.UIAxes, '1st Signal');
            xlabel(app.UIAxes, 't');
            ylabel(app.UIAxes, 'x');
            app.UIAxes2 = uiaxes(app.DiscreteConvolutionUIFigure);
            title(app.UIAxes2, '2nd Signal');
            xlabel(app.UIAxes2, 't');
            ylabel(app.UIAxes2, 'y');
            app.UIAxes3 = uiaxes(app.DiscreteConvolutionUIFigure);
            xlabel(app.UIAxes3, 't');
            ylabel(app.UIAxes3, 'x*y');
            app.UIAxes4 = uiaxes(app.DiscreteConvolutionUIFigure);
            xlabel(app.UIAxes4, 't');
            ylabel(app.UIAxes4, 'x*y');
            app.UIAxes5 = uiaxes(app.DiscreteConvolutionUIFigure);
            xlabel(app.UIAxes5, 't');
            ylabel(app.UIAxes5, 'x*y');
            app.UIAxes5.LineStyleOrder = {'- '};
            app.UIAxes6 = uiaxes(app.DiscreteConvolutionUIFigure);
            xlabel(app.UIAxes6, 't');
            ylabel(app.UIAxes6, 'x*y');
            app.FirstSignalPanel = uipanel(app.DiscreteConvolutionUIFigure);
            app.FirstSignalPanel.Title = 'First Signal:';
            app.FunctionLabel = uilabel(app.FirstSignalPanel);
            app.FunctionLabel.Text = 'Function:';
            app.FunctionDropDown = uidropdown(app.FirstSignalPanel);
            app.FunctionDropDown.Items = {'Rectangle', 'Triangle', 'Sine', ''};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Value = 'Rectangle';
            app.LengthLabel = uilabel(app.FirstSignalPanel);
            app.LengthLabel.Text = 'Length:';
            app.FunctionParameterField = uieditfield(app.FirstSignalPanel, 'numeric');
            app.FunctionParameterField.Limits = [0 Inf];
            app.FunctionParameterField.Value = 1;
            app.AmplitudeLabel = uilabel(app.FirstSignalPanel);
            app.AmplitudeLabel.Text = 'Amplitude:';
            app.FunctionAmplitudeField = uieditfield(app.FirstSignalPanel, 'numeric');
            app.FunctionAmplitudeField.Limits = [0 Inf];
            app.FunctionAmplitudeField.Value = 1;
            app.SecondSignalPanel = uipanel(app.DiscreteConvolutionUIFigure);
            app.SecondSignalPanel.Title = 'Second Signal:';
            app.FunctionLabel_2 = uilabel(app.SecondSignalPanel);
            app.FunctionLabel_2.Text = 'Function:';
            app.FunctionDropDown_2 = uidropdown(app.SecondSignalPanel);
            app.FunctionDropDown_2.Items = {'Rectangle', 'Triangle', 'Sine', ''};
            app.FunctionDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDown_2ValueChanged, true);
            app.FunctionDropDown_2.Value = 'Rectangle';
            app.LengthLabel_2 = uilabel(app.SecondSignalPanel);
            app.LengthLabel_2.Text = 'Length:';
            app.FunctionParameterField_2 = uieditfield(app.SecondSignalPanel, 'numeric');
            app.FunctionParameterField_2.Limits = [0 Inf];
            app.FunctionParameterField_2.Value = 1;
            app.AmplitudeLabel_2 = uilabel(app.SecondSignalPanel);
            app.AmplitudeLabel_2.Text = 'Amplitude:';
            app.FunctionAmplitudeField_2 = uieditfield(app.SecondSignalPanel, 'numeric');
            app.FunctionAmplitudeField_2.Limits = [0 Inf];
            app.FunctionAmplitudeField_2.Value = 1;
            app.PlotSettingsPanel = uipanel(app.DiscreteConvolutionUIFigure);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.XScaleLabel_2 = uilabel(app.PlotSettingsPanel);
            app.XScaleLabel_2.Text = 'XScale:';
            app.YScaleLabel_2 = uilabel(app.PlotSettingsPanel);
            app.YScaleLabel_2.Text = 'YScale:';
            app.TimeXScale = uidropdown(app.PlotSettingsPanel);
            app.TimeXScale.Items = {'Linear', 'Log'};
            app.TimeXScale.ValueChangedFcn = createCallbackFcn(app, @TimeXScaleValueChanged, true);
            app.TimeXScale.Value = 'Linear';
            app.TimeYScale = uidropdown(app.PlotSettingsPanel);
            app.TimeYScale.Items = {'Linear', 'Log'};
            app.TimeYScale.ValueChangedFcn = createCallbackFcn(app, @TimeYScaleValueChanged, true);
            app.TimeYScale.Value = 'Linear';
            app.PlotButton = uibutton(app.DiscreteConvolutionUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.VisualizationofDiscreteTimeConvolutionLabel = uilabel(app.DiscreteConvolutionUIFigure);
            app.VisualizationofDiscreteTimeConvolutionLabel.Text = 'Visualization of Discrete Time Convolution';
            app.SamplingLabel = uilabel(app.DiscreteConvolutionUIFigure);
            app.SamplingLabel.Text = 'Sampling';
            app.SamplingFrequencyField = uieditfield(app.DiscreteConvolutionUIFigure, 'numeric');
            app.SamplingFrequencyField.Limits = [0 Inf];
            app.SamplingFrequencyField.Value = 1;
            app.FrequencyLabel = uilabel(app.DiscreteConvolutionUIFigure);
            app.FrequencyLabel.Text = 'Frequency:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DiscreteConvolution()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiscreteConvolutionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiscreteConvolutionUIFigure)
        end
    end
end