classdef FilterDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FilterDesignFigure           matlab.ui.Figure
        UIAxesFrequencyResponse      matlab.ui.control.UIAxes
        BackButton                   matlab.ui.control.Button
        FilterDesignParametersPanel  matlab.ui.container.Panel
        DesiredOrderLabel            matlab.ui.control.Label
        DesiredOrderEditField        matlab.ui.control.NumericEditField
        FpassLabel                   matlab.ui.control.Label
        FpassEditField               matlab.ui.control.NumericEditField
        FstopLabel                   matlab.ui.control.Label
        FstopEditField               matlab.ui.control.NumericEditField
        DesignedOrderLabel           matlab.ui.control.Label
        DesignedOrderEditField       matlab.ui.control.NumericEditField
        TextArea                     matlab.ui.control.TextArea
        TextArea_2                   matlab.ui.control.TextArea
        PlotSettingsPanel            matlab.ui.container.Panel
        XScaleLabel                  matlab.ui.control.Label
        YScaleLabel                  matlab.ui.control.Label
        FrequencyXScale              matlab.ui.control.DropDown
        FrequencyYScale              matlab.ui.control.DropDown
        PlotTypeLabel                matlab.ui.control.Label
        FrequencyPlotType            matlab.ui.control.DropDown
        TextArea_3                   matlab.ui.control.TextArea
        DesignandPlotButton          matlab.ui.control.Button
        ErrorField                   matlab.ui.control.NumericEditField
        LeastSquaresErrorLabel       matlab.ui.control.Label
    end


    methods (Access = public)
    
        function [H,e] = filterls(~,n,f,h)
            A=zeros(length(f),n/2+1);
            h=transpose(h);
            for i=1:length(f)
                A(i,:)=horzcat(1,2*cos(2*pi*f(i)*(1:n/2)));
            end
            H=A\h;
            e=(norm(A*H-h))^2;
            H=transpose(H);
            H=horzcat(H(length(H):-1:2),H);
        end
        
    end


    methods (Access = private)

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            delete(app.FilterDesignFigure);
            MainApp();
        end

        % Button pushed function: DesignandPlotButton
        function DesignandPlotButtonPushed(app, event)
            Fp=app.FpassEditField.Value;
            Fs=app.FstopEditField.Value;
            if(Fp<Fs)
                cla(app.UIAxesFrequencyResponse);
                n_designed=app.DesignedOrderEditField.Value;
                n_desired=app.DesiredOrderEditField.Value;
                K_designed=5*n_designed;
                h_designed=[ones(1,K_designed/2) zeros(1,K_designed/2)];
                h_desired=[ones(1,n_desired/2) zeros(1,n_desired/2)];
                f_designed=[linspace(0,Fp,K_designed/2) linspace(Fs,0.5,K_designed/2)];
                f_desired=[linspace(0,Fp,n_desired/2) linspace(Fs,0.5,n_desired/2)];
                [lsH_designed,e]=filterls(app,n_designed,f_designed,h_designed);
                app.ErrorField.Value=e;
                [H_designed,w_designed] = freqz(lsH_designed,1,1024);
                type=app.FrequencyPlotType.Value;
                if(strcmp(type,'Magnitude')==1)
                    plot(app.UIAxesFrequencyResponse,w_designed/(2*pi),abs(H_designed));
                    plot(app.UIAxesFrequencyResponse,f_desired,h_desired);
                    legend(app.UIAxesFrequencyResponse,'Designed','Desired');
                    app.UIAxesFrequencyResponse.Legend.TextColor='k';
                    app.UIAxesFrequencyResponse.Legend.Color='w';
                    app.UIAxesFrequencyResponse.YLabel.String='|H|';
                elseif(strcmp(type,'Phase')==1)
                    plot(app.UIAxesFrequencyResponse,w_designed/(2*pi),angle(H_designed));
                    legend(app.UIAxesFrequencyResponse,'Designed')
                    app.UIAxesFrequencyResponse.Legend.TextColor='k';
                    app.UIAxesFrequencyResponse.Legend.Color='w';
                    app.UIAxesFrequencyResponse.YLabel.String='\angleH';
                elseif(strcmp(type,'Real')==1)
                    plot(app.UIAxesFrequencyResponse,w_designed/(2*pi),real(H_designed));
                    legend(app.UIAxesFrequencyResponse,'Designed');
                    app.UIAxesFrequencyResponse.Legend.TextColor='k';
                    app.UIAxesFrequencyResponse.Legend.Color='w';
                    app.UIAxesFrequencyResponse.YLabel.String='Real';
                elseif(strcmp(type,'Imaginary')==1)
                    plot(app.UIAxesFrequencyResponse,w_designed/(2*pi),imag(H_designed));
                    legend(app.UIAxesFrequencyResponse,'Designed');
                    app.UIAxesFrequencyResponse.Legend.TextColor='k';
                    app.UIAxesFrequencyResponse.Legend.Color='w';
                    app.UIAxesFrequencyResponse.YLabel.String='Imaginary';
                end
            else
                cla(app.UIAxesFrequencyResponse);
                uialert(app.FilterDesignFigure,'Fpass must be less than Fstop','Error');
            end
        end

        % Value changed function: FrequencyYScale
        function FrequencyYScaleValueChanged(app, event)
             app.UIAxesFrequencyResponse.YScale=app.FrequencyYScale.Value;
            
        end

        % Value changed function: FrequencyXScale
        function FrequencyXScaleValueChanged(app, event)
            app.UIAxesFrequencyResponse.XScale=app.FrequencyXScale.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FilterDesignFigure = uifigure;
            app.FilterDesignFigure.Name = 'FilterDesign';
            setAutoResize(app, app.FilterDesignFigure, true)
            app.UIAxesFrequencyResponse = uiaxes(app.FilterDesignFigure);
            title(app.UIAxesFrequencyResponse, 'Frequency Response');
            xlabel(app.UIAxesFrequencyResponse, 'Normalized Frequency with respect to fs/2');
            ylabel(app.UIAxesFrequencyResponse, 'H');
            app.UIAxesFrequencyResponse.LineStyleOrder = {'- '};
            app.BackButton = uibutton(app.FilterDesignFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.FilterDesignParametersPanel = uipanel(app.FilterDesignFigure);
            app.FilterDesignParametersPanel.Title = 'Filter Design Parameters';
            app.DesiredOrderLabel = uilabel(app.FilterDesignParametersPanel);
            app.DesiredOrderLabel.Text = 'Desired Order:';
            app.DesiredOrderEditField = uieditfield(app.FilterDesignParametersPanel, 'numeric');
            app.DesiredOrderEditField.Limits = [2 Inf];
            app.DesiredOrderEditField.Value = 2;
            app.FpassLabel = uilabel(app.FilterDesignParametersPanel);
            app.FpassLabel.Text = 'Fpass:';
            app.FpassEditField = uieditfield(app.FilterDesignParametersPanel, 'numeric');
            app.FpassEditField.Limits = [0 0.5];
            app.FstopLabel = uilabel(app.FilterDesignParametersPanel);
            app.FstopLabel.Text = 'Fstop:';
            app.FstopEditField = uieditfield(app.FilterDesignParametersPanel, 'numeric');
            app.FstopEditField.Limits = [0 0.5];
            app.DesignedOrderLabel = uilabel(app.FilterDesignParametersPanel);
            app.DesignedOrderLabel.Text = 'Designed Order:';
            app.DesignedOrderEditField = uieditfield(app.FilterDesignParametersPanel, 'numeric');
            app.DesignedOrderEditField.Limits = [2 Inf];
            app.DesignedOrderEditField.ValueDisplayFormat = '%11.3g';
            app.DesignedOrderEditField.Value = 2;
            app.TextArea = uitextarea(app.FilterDesignParametersPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Please enter your frequencies normalized with respect to half of the sampling frequency'};
            app.TextArea_2 = uitextarea(app.FilterDesignParametersPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Please note that the order must be an even number'; ''};
            app.PlotSettingsPanel = uipanel(app.FilterDesignFigure);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.XScaleLabel = uilabel(app.PlotSettingsPanel);
            app.XScaleLabel.Text = 'XScale:';
            app.YScaleLabel = uilabel(app.PlotSettingsPanel);
            app.YScaleLabel.Text = 'YScale:';
            app.FrequencyXScale = uidropdown(app.PlotSettingsPanel);
            app.FrequencyXScale.Items = {'Linear', 'Log', ''};
            app.FrequencyXScale.ValueChangedFcn = createCallbackFcn(app, @FrequencyXScaleValueChanged, true);
            app.FrequencyXScale.Value = 'Linear';
            app.FrequencyYScale = uidropdown(app.PlotSettingsPanel);
            app.FrequencyYScale.Items = {'Linear', 'Log', ''};
            app.FrequencyYScale.ValueChangedFcn = createCallbackFcn(app, @FrequencyYScaleValueChanged, true);
            app.FrequencyYScale.Value = 'Linear';
            app.PlotTypeLabel = uilabel(app.PlotSettingsPanel);
            app.PlotTypeLabel.Text = 'Plot Type:';
            app.FrequencyPlotType = uidropdown(app.PlotSettingsPanel);
            app.FrequencyPlotType.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary'};
            app.FrequencyPlotType.Value = 'Magnitude';
            app.TextArea_3 = uitextarea(app.PlotSettingsPanel);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'In order to display different plot types, you need to redesign; however, changing the scale is automatically triggered'; ''};
            app.DesignandPlotButton = uibutton(app.FilterDesignFigure, 'push');
            app.DesignandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @DesignandPlotButtonPushed, true);
            app.DesignandPlotButton.Text = 'Design and Plot';
            app.ErrorField = uieditfield(app.FilterDesignFigure, 'numeric');
            app.ErrorField.Editable = 'off';
            app.LeastSquaresErrorLabel = uilabel(app.FilterDesignFigure);
            app.LeastSquaresErrorLabel.Text = 'Least Squares Error:';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FilterDesign()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FilterDesignFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FilterDesignFigure)
        end
    end
end