classdef SpectralAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpectrumAnalyzerUIFigure   matlab.ui.Figure
        UIAxesFrequency            matlab.ui.control.UIAxes
        UIAxesTime                 matlab.ui.control.UIAxes
        PlotButton                 matlab.ui.control.Button
        AnalyzerSettingsPanel      matlab.ui.container.Panel
        FFTSizeLabel               matlab.ui.control.Label
        FFTSizeField               matlab.ui.control.NumericEditField
        WindowLabel                matlab.ui.control.Label
        WindowDropDown             matlab.ui.control.DropDown
        LengthLabel_2              matlab.ui.control.Label
        WindowLengthField          matlab.ui.control.NumericEditField
        FunctionPanel              matlab.ui.container.Panel
        SamplingLabel              matlab.ui.control.Label
        SamplingFrequencyField     matlab.ui.control.NumericEditField
        FunctionLabel              matlab.ui.control.Label
        FunctionDropDown           matlab.ui.control.DropDown
        LengthLabel                matlab.ui.control.Label
        FunctionParameterField     matlab.ui.control.NumericEditField
        FrequencyLabel             matlab.ui.control.Label
        PlotSettingsPanel          matlab.ui.container.Panel
        FrequencyDomainLabel_2     matlab.ui.control.Label
        XScaleLabel                matlab.ui.control.Label
        YScaleLabel                matlab.ui.control.Label
        FrequencyXScale            matlab.ui.control.DropDown
        FrequencyYScale            matlab.ui.control.DropDown
        TimeDomainLabel            matlab.ui.control.Label
        XScaleLabel_2              matlab.ui.control.Label
        YScaleLabel_2              matlab.ui.control.Label
        TimeXScale                 matlab.ui.control.DropDown
        TimeYScale                 matlab.ui.control.DropDown
        PlotTypeLabel              matlab.ui.control.Label
        FrequencyPlotType          matlab.ui.control.DropDown
        FrequencyBandsLabel        matlab.ui.control.Label
        FrequencyUpperLimit        matlab.ui.control.NumericEditField
        FrequencyLowerLimit        matlab.ui.control.NumericEditField
        EnableButton               matlab.ui.control.StateButton
        SoundFilesPanel            matlab.ui.container.Panel
        PleaseselectFileModeLabel  matlab.ui.control.Label
        andpastethefilepathLabel   matlab.ui.control.Label
        FilePathEditField          matlab.ui.control.EditField
        LoadButton                 matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        FilePathLabel              matlab.ui.control.Label
        AnalyzerModeButtonGroup    matlab.ui.container.ButtonGroup
        FileModeButton             matlab.ui.control.ToggleButton
        FunctionModeButton         matlab.ui.control.ToggleButton
        ComparisonModeButton       matlab.ui.control.Button
        RelevantQuantitiesPanel    matlab.ui.container.Panel
        RMSAverageLabel            matlab.ui.control.Label
        PeakLabel                  matlab.ui.control.Label
        TotalPowerLabel            matlab.ui.control.Label
        DCBiasLabel                matlab.ui.control.Label
        RMSField                   matlab.ui.control.NumericEditField
        PeakField                  matlab.ui.control.NumericEditField
        PowerField                 matlab.ui.control.NumericEditField
        DCBiasField                matlab.ui.control.NumericEditField
        CalculateButton            matlab.ui.control.Button
    end


    methods (Access = public)
    
        function func = tri(app,L)
            %%Generates a triangular pulse of length L
            L=L-1;
            t=0:1:L;
            func=(L/2-abs(t-L/2))/(L/2);
        end
        
    end


    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            L=app.WindowLengthField.Value;
            %%Defines the window chosen by the user
            if (strcmp(app.WindowDropDown.Value,'Rectangular')==1)
                w=ones(1,L);
            elseif (strcmp(app.WindowDropDown.Value,'Triangular')==1)
                w=tri(app,L);
            elseif (strcmp(app.WindowDropDown.Value,'Hamming')==1)
                temp=0:L-1;
                w=0.54-0.46*cos(2*pi*temp/(L-1));
            elseif (strcmp(app.WindowDropDown.Value,'Hanning')==1)
                temp=0:L-1;
                w=(sin(pi*temp/(L-1))).^2;
            end
            if(app.FunctionModeButton.Value==1)
                if (strcmp(app.FunctionDropDown.Value,'Rectangle')==1)
                    %%reads the parameters of the function
                    L=app.FunctionParameterField.Value;
                    fs=app.SamplingFrequencyField.Value;
                    N=app.FFTSizeField.Value;
                    t=0:1/fs:(L-1)/fs;
                    y=ones(1,L);
                    %windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    %time domain plot
                    plot(app.UIAxesTime,t(1:length(y)),y);
                    %frequency domain plot
                    type=app.FrequencyPlotType.Value;
                    f=-fs/2:fs/N:fs/2-fs/N;
                    f_y=fft(y,N);
                    if(strcmp(type,'Magnitude')==1)
                        f_y=abs(fftshift(f_y));
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='|X|';
                    elseif(strcmp(type,'Phase')==1)
                        f_y=angle(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='\angleX';
                    elseif(strcmp(type,'Real')==1)
                        f_y=real(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Real';
                    elseif(strcmp(type,'Imaginary')==1)
                        f_y=imag(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Imaginary';
                    end
                elseif (strcmp(app.FunctionDropDown.Value,'Triangle')==1)
                    %%reads the parameters of the function
                    L=app.FunctionParameterField.Value;
                    fs=app.SamplingFrequencyField.Value;
                    N=app.FFTSizeField.Value;
                    %time domain plot
                    t=0:1/fs:(L)/fs;
                    y=tri(app,L);
                    %Windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    %time domain plot
                    plot(app.UIAxesTime,t(1:length(y)),y);
                    %frequency domain plot
                    type=app.FrequencyPlotType.Value;
                    f=-fs/2:fs/N:fs/2-fs/N;
                    f_y=fft(y,N);
                    if(strcmp(type,'Magnitude')==1)
                        f_y=abs(fftshift(f_y));
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='|X|';
                    elseif(strcmp(type,'Phase')==1)
                        f_y=angle(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='\angleX';
                    elseif(strcmp(type,'Real')==1)
                        f_y=real(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Real';
                    elseif(strcmp(type,'Imaginary')==1)
                        f_y=imag(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Imaginary';
                    end
                elseif (strcmp(app.FunctionDropDown.Value,'Sine')==1)
                    %%reads the parameters of the function
                    T=app.FunctionParameterField.Value;
                    fs=app.SamplingFrequencyField.Value;
                    N=app.FFTSizeField.Value;
                    omega=2*pi/T;
                    t=0:1/fs:2*T;
                    y=sin(omega*t);
                    %windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    %time domain plot
                    plot(app.UIAxesTime,t(1:length(y)),y);
                    %frequency domain plot
                    type=app.FrequencyPlotType.Value;
                    f=-fs/2:fs/N:fs/2-fs/N;
                    f_y=fft(y,N);
                    if(strcmp(type,'Magnitude')==1)
                        f_y=abs(fftshift(f_y));
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='|X|';
                    elseif(strcmp(type,'Phase')==1)
                        f_y=angle(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='\angleX';
                    elseif(strcmp(type,'Real')==1)
                        f_y=real(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Real';
                    elseif(strcmp(type,'Imaginary')==1)
                        f_y=imag(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Imaginary';
                    end
                end
            elseif(app.FileModeButton.Value==1)
                if(exist(app.FilePathEditField.Value)==2)
                    %%reads the audio file
                    [y, fs]=audioread(app.FilePathEditField.Value);
                    y=transpose(y);
                    N=app.FFTSizeField.Value;
                    L=length(y);
                    t=0:1/fs:(L-1)/fs;
                    %windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    %time domain plot
                    plot(app.UIAxesTime,t(1:length(y)),y);
                    %frequency domain plot
                    type=app.FrequencyPlotType.Value;
                    f=-fs/2:fs/N:fs/2-fs/N;
                    f_y=fft(y,N);
                    if(strcmp(type,'Magnitude')==1)
                        f_y=abs(fftshift(f_y));
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='|X|';
                    elseif(strcmp(type,'Phase')==1)
                        f_y=angle(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='\angleX';
                    elseif(strcmp(type,'Real')==1)
                        f_y=real(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Real';
                    elseif(strcmp(type,'Imaginary')==1)
                        f_y=imag(f_y);
                        plot(app.UIAxesFrequency,f,f_y);
                        app.UIAxesFrequency.YLabel.String='Imaginary';
                    end    
                else
                    uialert(app.SpectrumAnalyzerUIFigure,'Something seems to be wrong with your file','Invalid File');
                end
            end
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            value = app.FunctionDropDown.Value;
            %%sets the label to its appropriate name according to the chosen function
            if(strcmp(value,'Sine')==1)
                app.LengthLabel.Text='Period:';
            elseif(strcmp(value,'Rectangle')==1)
                 app.LengthLabel.Text='Length:';
            elseif(strcmp(value,'Triangle')==1)
                app.LengthLabel.Text='Length:';
            end
        end

        % Value changed function: FrequencyXScale
        function FrequencyXScaleValueChanged(app, event)
            app.UIAxesFrequency.XScale=app.FrequencyXScale.Value;
        end

        % Value changed function: FrequencyYScale
        function FrequencyYScaleValueChanged(app, event)
            app.UIAxesFrequency.YScale=app.FrequencyYScale.Value;
        end

        % Value changed function: TimeXScale
        function TimeXScaleValueChanged(app, event)
            app.UIAxesTime.XScale=app.TimeXScale.Value;
        end

        % Value changed function: TimeYScale
        function TimeYScaleValueChanged(app, event)
            app.UIAxesTime.YScale=app.TimeYScale.Value;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if(exist(app.FilePathEditField.Value)==2)
                %%loads the audio file and alerts the user if the file is not found
                [y,Fs]=audioread(app.FilePathEditField.Value);
                app.SamplingFrequencyField.Value=Fs;
                app.FunctionParameterField.Value=length(y);
                uialert(app.SpectrumAnalyzerUIFigure,'Your file is loaded','')
            else
                uialert(app.SpectrumAnalyzerUIFigure,'file not found','Invalid File');
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
           %%Checks if the audio file is in the directory and plays it
            if(exist(app.FilePathEditField.Value)==2)
                [y,Fs]=audioread(app.FilePathEditField.Value);
                sound(y,Fs);
            else
                uialert(app.SpectrumAnalyzerUIFigure,'file not found','Invalid File');
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end

        % Selection changed function: AnalyzerModeButtonGroup
        function AnalyzerModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AnalyzerModeButtonGroup.SelectedObject;
            %%Changes the state of panels and objects in the panel according the analyzer mode
            if(strcmp(selectedButton.Text,'File Mode')==1)
                app.SamplingFrequencyField.Enable='off';
                app.FunctionParameterField.Enable='off';
                app.FunctionDropDown.Visible='off';
                app.FunctionLabel.Visible='off';
                app.FunctionPanel.Title='File:';
                app.LengthLabel.Text='Length:';
                app.FilePathEditField.Enable='on';
                app.LoadButton.Enable='on';
                app.PlayButton.Enable='on';
                app.StopButton.Enable='on';
            elseif(strcmp(selectedButton.Text,'Function Mode')==1)
                app.SamplingFrequencyField.Enable='on';
                app.FunctionParameterField.Enable='on';
                app.FunctionDropDown.Visible='on';
                app.FunctionLabel.Visible='on';
                app.FunctionPanel.Title='Function:';
                app.FilePathEditField.Enable='off';
                app.LoadButton.Enable='off';
                app.PlayButton.Enable='off';
                app.StopButton.Enable='off';
            end
        end

        % Button pushed function: ComparisonModeButton
        function ComparisonModeButtonPushed(app, event)
            ComparisonMode();
            delete(app.SpectrumAnalyzerUIFigure)
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            L=app.WindowLengthField.Value;
            %%Defines the window chosen by the user
            if (strcmp(app.WindowDropDown.Value,'Rectangular')==1)
                w=ones(1,L);
            elseif (strcmp(app.WindowDropDown.Value,'Triangular')==1)
                w=tri(app,L);
            elseif (strcmp(app.WindowDropDown.Value,'Hamming')==1)
                temp=0:L;
                w=0.54-0.46*cos(2*pi*temp/L);
            elseif (strcmp(app.WindowDropDown.Value,'Hanning')==1)
                temp=0:L-1;
                w=(sin(pi*temp/(L-1))).^2;
            end
             if(app.FunctionModeButton.Value==1)
                if (strcmp(app.FunctionDropDown.Value,'Rectangle')==1)
                    L=app.FunctionParameterField.Value;
                    fs=app.SamplingFrequencyField.Value;
                    N=app.FFTSizeField.Value;
                    t=0:1/fs:(L-1)/fs;
                    y=ones(1,L);
                    %windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    f_y=fft(y,N);
                    %%Calculates the required parameters
                    app.PeakField.Value=max(y);
                    app.DCBiasField.Value=abs(f_y(1));
                    app.PowerField.Value=trapz(t(1:length(y)),y.*y)/t(length(y));
                    app.RMSField.Value=sqrt(app.PowerField.Value);
                elseif (strcmp(app.FunctionDropDown.Value,'Triangle')==1)
                    L=app.FunctionParameterField.Value;
                    fs=app.SamplingFrequencyField.Value;
                    N=app.FFTSizeField.Value;
                    t=0:1/fs:(L)/fs;
                    y=tri(app,L);
                    %Windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    f_y=fft(y,N);
                    %%Calculates the required parameters
                    app.PeakField.Value=max(y);
                    app.DCBiasField.Value=abs(f_y(1));
                    app.PowerField.Value=trapz(t(1:length(y)),y.*y)/t(length(y));
                    app.RMSField.Value=sqrt(app.PowerField.Value);
                elseif (strcmp(app.FunctionDropDown.Value,'Sine')==1)
                    T=app.FunctionParameterField.Value;
                    fs=app.SamplingFrequencyField.Value;
                    N=app.FFTSizeField.Value;
                    omega=2*pi/T;
                    t=0:1/fs:2*T;
                    y=sin(omega*t);
                    %windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    f_y=fft(y,N);
                    %%Calculates the required parameters
                    app.PeakField.Value=max(y);
                    app.DCBiasField.Value=abs(f_y(1));
                    app.PowerField.Value=trapz(t(1:length(y)),y.*y)/t(length(y));
                    app.RMSField.Value=sqrt(app.PowerField.Value);
                end
            elseif(app.FileModeButton.Value==1)
                if(exist(app.FilePathEditField.Value)==2)
                    %%Reads the audio file
                    [y fs]=audioread(app.FilePathEditField.Value);
                    y=transpose(y);
                    N=app.FFTSizeField.Value;
                    L=length(y);
                    t=0:1/fs:(L-1)/fs;
                    %windowing
                    if(length(w)>length(t))
                        y=y.*w(1:length(y));
                    else
                        y=y(1:length(w)).*w;
                    end
                    f_y=fft(y,N);
                    %%Calculates the required parameters
                    app.PeakField.Value=max(y);
                    app.DCBiasField.Value=abs(f_y(1));
                    app.PowerField.Value=trapz(t(1:length(y)),y.*y)/t(length(y));
                    app.RMSField.Value=sqrt(app.PowerField.Value);
                else
                    uialert(app.SpectrumAnalyzerUIFigure,'Something seems to be wrong with your file','Invalid File');
                end
            end
        end

        % Value changed function: FrequencyLowerLimit
        function FrequencyLowerLimitValueChanged(app, event)
            if(app.FrequencyUpperLimit.Value>app.FrequencyLowerLimit.Value) 
                app.UIAxesFrequency.XLim=[app.FrequencyLowerLimit.Value app.FrequencyUpperLimit.Value];
            end
        end

        % Value changed function: FrequencyUpperLimit
        function FrequencyUpperLimitValueChanged(app, event)
            if(app.FrequencyUpperLimit.Value>app.FrequencyLowerLimit.Value) 
                app.UIAxesFrequency.XLim=[app.FrequencyLowerLimit.Value app.FrequencyUpperLimit.Value];
            end
        end

        % Value changed function: EnableButton
        function EnableButtonValueChanged(app, event)
            value = app.EnableButton.Value;
            if(value==1)
                app.FrequencyUpperLimit.Enable='on';
                app.FrequencyLowerLimit.Enable='on';
            else
                app.FrequencyUpperLimit.Enable='off';
                app.FrequencyLowerLimit.Enable='off';
                app.UIAxesFrequency.XLim=[-app.SamplingFrequencyField.Value/2 app.SamplingFrequencyField.Value/2];
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpectrumAnalyzerUIFigure = uifigure;
            app.SpectrumAnalyzerUIFigure.Name = 'Spectrum Analyzer';
            setAutoResize(app, app.SpectrumAnalyzerUIFigure, true)
            app.UIAxesFrequency = uiaxes(app.SpectrumAnalyzerUIFigure);
            title(app.UIAxesFrequency, 'Frequency Domain');
            xlabel(app.UIAxesFrequency, 'f');
            ylabel(app.UIAxesFrequency, 'X');
            app.UIAxesFrequency.LineStyleOrder = {'- '};
            app.UIAxesTime = uiaxes(app.SpectrumAnalyzerUIFigure);
            title(app.UIAxesTime, 'Time Domain');
            xlabel(app.UIAxesTime, 't');
            ylabel(app.UIAxesTime, 'x');
            app.PlotButton = uibutton(app.SpectrumAnalyzerUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.AnalyzerSettingsPanel = uipanel(app.SpectrumAnalyzerUIFigure);
            app.AnalyzerSettingsPanel.Title = 'Analyzer Settings';
            app.FFTSizeLabel = uilabel(app.AnalyzerSettingsPanel);
            app.FFTSizeLabel.Text = 'FFT Size:';
            app.FFTSizeField = uieditfield(app.AnalyzerSettingsPanel, 'numeric');
            app.FFTSizeField.Limits = [1 Inf];
            app.FFTSizeField.Value = 1;
            app.WindowLabel = uilabel(app.AnalyzerSettingsPanel);
            app.WindowLabel.Text = 'Window:';
            app.WindowDropDown = uidropdown(app.AnalyzerSettingsPanel);
            app.WindowDropDown.Items = {'Rectangular', 'Triangular', 'Hamming', 'Hanning'};
            app.WindowDropDown.Value = 'Rectangular';
            app.LengthLabel_2 = uilabel(app.AnalyzerSettingsPanel);
            app.LengthLabel_2.Text = 'Length:';
            app.WindowLengthField = uieditfield(app.AnalyzerSettingsPanel, 'numeric');
            app.WindowLengthField.Limits = [1 Inf];
            app.WindowLengthField.Value = 1;
            app.FunctionPanel = uipanel(app.SpectrumAnalyzerUIFigure);
            app.FunctionPanel.Title = 'Function:';
            app.SamplingLabel = uilabel(app.FunctionPanel);
            app.SamplingLabel.Text = 'Sampling';
            app.SamplingFrequencyField = uieditfield(app.FunctionPanel, 'numeric');
            app.SamplingFrequencyField.Limits = [1 Inf];
            app.SamplingFrequencyField.Value = 1;
            app.FunctionLabel = uilabel(app.FunctionPanel);
            app.FunctionLabel.Text = 'Function:';
            app.FunctionDropDown = uidropdown(app.FunctionPanel);
            app.FunctionDropDown.Items = {'Rectangle', 'Triangle', 'Sine', ''};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Value = 'Rectangle';
            app.LengthLabel = uilabel(app.FunctionPanel);
            app.LengthLabel.Text = 'Length:';
            app.FunctionParameterField = uieditfield(app.FunctionPanel, 'numeric');
            app.FunctionParameterField.Limits = [0 Inf];
            app.FunctionParameterField.Value = 1;
            app.FrequencyLabel = uilabel(app.FunctionPanel);
            app.FrequencyLabel.Text = 'Frequency:';
            app.PlotSettingsPanel = uipanel(app.SpectrumAnalyzerUIFigure);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.FrequencyDomainLabel_2 = uilabel(app.PlotSettingsPanel);
            app.FrequencyDomainLabel_2.Text = 'Frequency Domain:';
            app.XScaleLabel = uilabel(app.PlotSettingsPanel);
            app.XScaleLabel.Text = 'XScale:';
            app.YScaleLabel = uilabel(app.PlotSettingsPanel);
            app.YScaleLabel.Text = 'YScale:';
            app.FrequencyXScale = uidropdown(app.PlotSettingsPanel);
            app.FrequencyXScale.Items = {'Linear', 'Log', ''};
            app.FrequencyXScale.ValueChangedFcn = createCallbackFcn(app, @FrequencyXScaleValueChanged, true);
            app.FrequencyXScale.Value = 'Linear';
            app.FrequencyYScale = uidropdown(app.PlotSettingsPanel);
            app.FrequencyYScale.Items = {'Linear', 'Log', ''};
            app.FrequencyYScale.ValueChangedFcn = createCallbackFcn(app, @FrequencyYScaleValueChanged, true);
            app.FrequencyYScale.Value = 'Linear';
            app.TimeDomainLabel = uilabel(app.PlotSettingsPanel);
            app.TimeDomainLabel.Text = 'Time Domain:';
            app.XScaleLabel_2 = uilabel(app.PlotSettingsPanel);
            app.XScaleLabel_2.Text = 'XScale:';
            app.YScaleLabel_2 = uilabel(app.PlotSettingsPanel);
            app.YScaleLabel_2.Text = 'YScale:';
            app.TimeXScale = uidropdown(app.PlotSettingsPanel);
            app.TimeXScale.Items = {'Linear', 'Log'};
            app.TimeXScale.ValueChangedFcn = createCallbackFcn(app, @TimeXScaleValueChanged, true);
            app.TimeXScale.Value = 'Linear';
            app.TimeYScale = uidropdown(app.PlotSettingsPanel);
            app.TimeYScale.Items = {'Linear', 'Log'};
            app.TimeYScale.ValueChangedFcn = createCallbackFcn(app, @TimeYScaleValueChanged, true);
            app.TimeYScale.Value = 'Linear';
            app.PlotTypeLabel = uilabel(app.PlotSettingsPanel);
            app.PlotTypeLabel.Text = 'Plot Type:';
            app.FrequencyPlotType = uidropdown(app.PlotSettingsPanel);
            app.FrequencyPlotType.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary'};
            app.FrequencyPlotType.Value = 'Magnitude';
            app.FrequencyBandsLabel = uilabel(app.PlotSettingsPanel);
            app.FrequencyBandsLabel.Text = 'Frequency Bands:';
            app.FrequencyUpperLimit = uieditfield(app.PlotSettingsPanel, 'numeric');
            app.FrequencyUpperLimit.ValueChangedFcn = createCallbackFcn(app, @FrequencyUpperLimitValueChanged, true);
            app.FrequencyUpperLimit.Enable = 'off';
            app.FrequencyLowerLimit = uieditfield(app.PlotSettingsPanel, 'numeric');
            app.FrequencyLowerLimit.ValueChangedFcn = createCallbackFcn(app, @FrequencyLowerLimitValueChanged, true);
            app.FrequencyLowerLimit.Enable = 'off';
            app.EnableButton = uibutton(app.PlotSettingsPanel, 'state');
            app.EnableButton.ValueChangedFcn = createCallbackFcn(app, @EnableButtonValueChanged, true);
            app.EnableButton.Text = 'Enable';
            app.SoundFilesPanel = uipanel(app.SpectrumAnalyzerUIFigure);
            app.SoundFilesPanel.Title = 'Sound Files';
            app.PleaseselectFileModeLabel = uilabel(app.SoundFilesPanel);
            app.PleaseselectFileModeLabel.Text = 'Please select File Mode';
            app.andpastethefilepathLabel = uilabel(app.SoundFilesPanel);
            app.andpastethefilepathLabel.Text = 'and paste the file path:';
            app.FilePathEditField = uieditfield(app.SoundFilesPanel, 'text');
            app.FilePathEditField.Enable = 'off';
            app.LoadButton = uibutton(app.SoundFilesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.PlayButton = uibutton(app.SoundFilesPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.SoundFilesPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.FilePathLabel = uilabel(app.SoundFilesPanel);
            app.FilePathLabel.Text = 'File Path:';
            app.AnalyzerModeButtonGroup = uibuttongroup(app.SpectrumAnalyzerUIFigure);
            app.AnalyzerModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalyzerModeButtonGroupSelectionChanged, true);
            app.AnalyzerModeButtonGroup.Title = 'Analyzer Mode:';
            app.FileModeButton = uitogglebutton(app.AnalyzerModeButtonGroup);
            app.FileModeButton.Text = 'File Mode';
            app.FunctionModeButton = uitogglebutton(app.AnalyzerModeButtonGroup);
            app.FunctionModeButton.Text = 'Function Mode';
            app.FunctionModeButton.Value = true;
            app.ComparisonModeButton = uibutton(app.SpectrumAnalyzerUIFigure, 'push');
            app.ComparisonModeButton.ButtonPushedFcn = createCallbackFcn(app, @ComparisonModeButtonPushed, true);
            app.ComparisonModeButton.Text = 'Comparison Mode';
            app.RelevantQuantitiesPanel = uipanel(app.SpectrumAnalyzerUIFigure);
            app.RelevantQuantitiesPanel.Title = 'Relevant Quantities';
            app.RMSAverageLabel = uilabel(app.RelevantQuantitiesPanel);
            app.RMSAverageLabel.Text = 'RMS Average:';
            app.PeakLabel = uilabel(app.RelevantQuantitiesPanel);
            app.PeakLabel.Text = 'Peak:';
            app.TotalPowerLabel = uilabel(app.RelevantQuantitiesPanel);
            app.TotalPowerLabel.Text = 'Total Power:';
            app.DCBiasLabel = uilabel(app.RelevantQuantitiesPanel);
            app.DCBiasLabel.Text = 'DC Bias:';
            app.RMSField = uieditfield(app.RelevantQuantitiesPanel, 'numeric');
            app.RMSField.Editable = 'off';
            app.PeakField = uieditfield(app.RelevantQuantitiesPanel, 'numeric');
            app.PeakField.Editable = 'off';
            app.PowerField = uieditfield(app.RelevantQuantitiesPanel, 'numeric');
            app.PowerField.Editable = 'off';
            app.DCBiasField = uieditfield(app.RelevantQuantitiesPanel, 'numeric');
            app.DCBiasField.Editable = 'off';
            app.CalculateButton = uibutton(app.SpectrumAnalyzerUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SpectralAnalyzer()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpectrumAnalyzerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpectrumAnalyzerUIFigure)
        end
    end
end